classdef GridSentry < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label                           matlab.ui.control.Label
        SolarWatchLineDropDown          matlab.ui.control.DropDown
        SolarWatchLineDropDownLabel     matlab.ui.control.Label
        Run24HourSolarSimulationButton  matlab.ui.control.Button
        SimulateLineOutageButton        matlab.ui.control.Button
        LineSelectorDropDown            matlab.ui.control.DropDown
        LineSelectorDropDownLabel       matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        RunBaseCaseAnalysisButton       matlab.ui.control.Button
        PowerSystemSecurityAnalyzerLabel  matlab.ui.control.Label
        HourlyLoadingAxes               matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        mpc 
    end
    
    methods (Access = private)
        
        function results = plotGrid(app,results)
            % Get the system data
            mpc = app.mpc;
            
            % Define the (x, y) coordinates for each of the 6 buses
            x_coords = [1 3 5 2 4 6]; y_coords = [3 3 3 1 1 1];
            
            % Get the 'from' and 'to' bus lists for all branches
            from_bus = mpc.branch(:, 1); to_bus = mpc.branch(:, 2);
            
            % Create a graph object
            g = graph(from_bus, to_bus);
            
            % Plot the basic graph. We save the plot object to 'p' to modify it.
            p = plot(app.UIAxes, g, 'XData', x_coords, 'YData', y_coords, ...
                'NodeColor', 'b', 'MarkerSize', 10);
            
            % If we have results, color the lines (edges) based on their loading
            if ~isempty(results) && results.success == 1
                % Get the MVA rating and power flow for each branch
                limit_MVA = results.branch(:, 6); % Column 6 is rateA
                flow_MVA_from = sqrt(results.branch(:, 14).^2 + results.branch(:, 15).^2); % Pfrom^2 + Qfrom^2
                flow_MVA_to = sqrt(results.branch(:, 16).^2 + results.branch(:, 17).^2); % Pto^2 + Qto^2
                
                actual_flow = max(flow_MVA_from, flow_MVA_to);
                
                % Calculate loading percentage
                loading_pct = zeros(size(limit_MVA));
                has_limit = limit_MVA > 0;
                loading_pct(has_limit) = (actual_flow(has_limit) ./ limit_MVA(has_limit)) * 100;
                
                % <<< --- THIS IS THE CORRECTED LOGIC --- >>>
                % 1. Create a matrix to hold the RGB color for each line
                num_branches = size(results.branch, 1);
                EdgeColors = zeros(num_branches, 3); % Initialize all to black
                
                % 2. Loop through each branch and fill the color matrix
                for i = 1:num_branches
                    if loading_pct(i) > 85  % Was 95
                        EdgeColors(i, :) = [1 0 0]; % Red
                    elseif loading_pct(i) > 65  % Was 75
                        EdgeColors(i, :) = [1 1 0]; % Yellow
                    else
                        EdgeColors(i, :) = [0 1 0]; % Green
                    end
                end
                
                % 3. Apply the color map and line widths to the plot in ONE command
                p.EdgeColor = EdgeColors;
                p.LineWidth = 3;
                
                title(app.UIAxes, 'System Loading Diagram');
            else
                % If there are no results yet, just make the lines black and thin
                p.EdgeColor = 'k';
                p.LineWidth = 2;
                title(app.UIAxes, '6-Bus Power System Diagram');
            end
            
            % Add labels and clean up the axes (this runs every time)
            bus_labels = cellstr(num2str((1:6)')); 
            text(app.UIAxes, x_coords, y_coords, bus_labels, ...
                'VerticalAlignment','bottom', 'HorizontalAlignment','right', ...
                'FontSize', 14, 'Color', 'r');
            app.UIAxes.XTick = []; app.UIAxes.YTick = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % --- Define the System (MATPOWER Case Format) ---
            mpc.version = '2';
            mpc.baseMVA = 100;
            mpc.bus = [
                1,    3,     0,   0,   0,   0,   1,     1.05,  0,    0,       1,     1.1,   0.9;
                2,    2,     0,   0,   0,   0,   1,     1.05,  0,    0,       1,     1.1,   0.9;
                3,    2,     0,   0,   0,   0,   1,     1.07,  0,    0,       1,     1.1,   0.9;
                4,    1,     70,  70,  0,   0,   1,     1.00,  0,    0,       1,     1.1,   0.9;
                5,    1,     70,  70,  0,   0,   1,     1.00,  0,    0,       1,     1.1,   0.9;
                6,    1,     70,  70,  0,   0,   1,     1.00,  0,    0,       1,     1.1,   0.9;
            ];
            mpc.gen = [
                1,   0,  0,  999, -999, 1.05, 100,  1,   999,  -999;
                2,   50, 0,  100, -100, 1.05, 100,  1,   100,     0;
                3,   60, 0,  100, -100, 1.07, 100,  1,   100,     0;
            ];
            mpc.branch = [
                1,    2,    0.0010,    0.2000,    0.0400,    100,    100,    100,    0,     0,      1,    -360,   360;
                1,    4,    0.0005,    0.2000,    0.0400,    100,    100,    100,    0,     0,      1,    -360,   360;
                1,    5,    0.0008,    0.3000,    0.0600,    100,    100,    100,    0,     0,      1,    -360,   360;
                2,    3,    0.0005,    0.2500,    0.0600,    100,    100,    100,    0,     0,      1,    -360,   360;
                2,    4,    0.0005,    0.1000,    0.0200,    100,    100,    100,    0,     0,      1,    -360,   360;
                2,    5,    0.0010,    0.3000,    0.0400,    100,    100,    100,    0,     0,      1,    -360,   360;
                2,    6,    0.0007,    0.2000,    0.0500,    100,    100,    100,    0,     0,      1,    -360,   360;
                3,    5,    0.0012,    0.2600,    0.0500,    100,    100,    100,    0,     0,      1,    -360,   360;
                3,    6,    0.0002,    0.1000,    0.0200,    100,    100,    100,    0,     0,      1,    -360,   360;
                4,    5,    0.0020,    0.4000,    0.0800,    100,    100,    100,    0,     0,      1,    -360,   360;
                5,    6,    0.0010,    0.3000,    0.0600,    100,    100,    100,    0,     0,      1,    -360,   360;
            ];
            
            % Save the completed mpc structure to the app's property
            app.mpc = mpc;
            
            % Draw the initial grid diagram
            plotGrid(app,[]);   


            % --- Populate the Line Selector Dropdown ---
            num_branches = size(app.mpc.branch, 1);
            line_names = cell(num_branches, 1);
            for i = 1:num_branches
                from_bus = app.mpc.branch(i, 1);
                to_bus = app.mpc.branch(i, 2);
                line_names{i} = sprintf('Line %d-%d', from_bus, to_bus);
            end
            app.LineSelectorDropDown.Items = line_names;            

            % --- Populate the NEW Solar Watch Line Selector Dropdown ---
            % (This code is very similar to the first dropdown's population)
            num_branches = size(app.mpc.branch, 1);
            line_names = cell(num_branches, 1);
            for i = 1:num_branches
                from_bus = app.mpc.branch(i, 1);
                to_bus = app.mpc.branch(i, 2);
                line_names{i} = sprintf('Line %d-%d', from_bus, to_bus);
            end
            app.SolarWatchLineDropDown.Items = line_names;
            % Set the default value to our old "watch line"
            app.SolarWatchLineDropDown.Value = 'Line 3-6';            
        end

        % Button pushed function: RunBaseCaseAnalysisButton
        function RunBaseCaseAnalysisButtonPushed(app, event)
            
                % --- 1. Get the system data from the app's properties ---
                mpc = app.mpc;
            
                % --- 2. Run the power flow solver ---
                results = runpf(mpc);
            
                % --- 3. Update the plot with the results ---
                plotGrid(app, results);
            
                % --- 4. Generate a full Base Case Report ---
                if results.success == 1
                    % --- Build the new, more insightful report ---
                    report_text = ["Base Case Analysis Report"; ...
                                   "========================="; ...
                                   "This shows the grid in its normal, healthy operating state."; ...
                                   " "];
            
                    % --- Analyze base case results to form a conclusion ---
                    % Calculate loading for all lines in the base case
                    limit_MVA = results.branch(:, 6);
                    flow_from = sqrt(results.branch(:, 14).^2 + results.branch(:, 15).^2);
                    flow_to = sqrt(results.branch(:, 16).^2 + results.branch(:, 17).^2);
                    actual_flow = max(flow_from, flow_to);
                    
                    loading_pct = zeros(size(limit_MVA));
                    has_limit = limit_MVA > 0;
                    loading_pct(has_limit) = (actual_flow(has_limit) ./ limit_MVA(has_limit)) * 100;
                    
                    % <<<--- CORRECTED LOGIC: Find the TRUE maximum loading ---<<<
                    [max_loading_base_case, most_stressed_line_index] = max(loading_pct);
                    
                    % Add the conclusion to the report
                    report_text = [report_text; "SYSTEM STATUS:"];
                    if max_loading_base_case > 65
                        % <<<--- CORRECTED LOGIC: Report on the TRUE most stressed line ---<<<
                        line_from = results.branch(most_stressed_line_index, 1);
                        line_to = results.branch(most_stressed_line_index, 2);
                        report_text = [report_text; ...
                            sprintf("System is HEALTHY but CONGESTED. Peak loading is %.1f%% on Line %d-%d.", ...
                            max_loading_base_case, line_from, line_to)];
                    else
                        report_text = [report_text; "System is HEALTHY and SECURE. All line loadings are normal."];
                    end
                    
                    % Add the bus voltage table for detailed reference
                    report_text = [report_text; " "; "--- Bus Voltages (p.u.) & Angles (deg) ---"];
                    bus_results_lines = strings(size(results.bus, 1), 1);
                    for i = 1:size(results.bus, 1)
                        bus_results_lines(i) = sprintf(' Bus %d:    %1.4f V   at   %1.4f deg', ...
                            results.bus(i, 1), results.bus(i, 8), results.bus(i, 9));
                    end
                    report_text = [report_text; bus_results_lines];
                    
                    app.TextArea.Value = report_text;
                    
                else
                    app.TextArea.Value = "Base case power flow FAILED to solve. Check the system data.";
                end


                app.UIAxes.Color = 'white';
        end

        % Button pushed function: SimulateLineOutageButton
        function SimulateLineOutageButtonPushed(app, event)
                        
            % --- 1. Get the current system and the selected line ---
            mpc_contingency = app.mpc; % Create a temporary copy
            selected_line_name = app.LineSelectorDropDown.Value;
            selected_line_index = find(strcmp(app.LineSelectorDropDown.Items, selected_line_name));
            if isempty(selected_line_index), selected_line_index = 1; end
            
            % --- 2. Simulate the outage ---
            mpc_contingency.branch(selected_line_index, 11) = 0; % Turn the line OFF
            
            % --- 3. Run the contingency power flow ---
            opt = mpoption('OUT_ALL', 0); % Suppress summary output
            results = runpf(mpc_contingency, opt);
            
            % --- 4. Update the GUI and Generate a Report with Conclusion ---
            if results.success == 1
                % Update the plot with the new results
                plotGrid(app, results);
                
                % --- Build the new, more insightful report ---
                report_text = [sprintf("Contingency Report: %s OUTAGE", selected_line_name); ...
                               "====================================="; ...
                               " "; ...
                               "Bus |   Voltage (p.u.) |   Angle (deg)"; ...
                               "----|------------------|----------------"];
                
                % Add the bus results to the report
                bus_results_lines = strings(size(results.bus, 1), 1);
                for i = 1:size(results.bus, 1)
                    bus_results_lines(i) = sprintf(' %d  |      %1.4f      |     %1.4f', ...
                        results.bus(i, 1), results.bus(i, 8), results.bus(i, 9));
                end
                report_text = [report_text; bus_results_lines; " "];
                
                % <<< --- NEW: Analyze results to form a conclusion --- >>>
                % Calculate loading for all lines after the outage
                limit_MVA = results.branch(:, 6);
                flow_from = sqrt(results.branch(:, 14).^2 + results.branch(:, 15).^2);
                flow_to = sqrt(results.branch(:, 16).^2 + results.branch(:, 17).^2);
                actual_flow = max(flow_from, flow_to);
                
                loading_pct = zeros(size(limit_MVA));
                has_limit = limit_MVA > 0;
                loading_pct(has_limit) = (actual_flow(has_limit) ./ limit_MVA(has_limit)) * 100;
                
                % Find the new maximum loading
                [max_loading_after_outage, most_stressed_line_index] = max(loading_pct);
                
                % Add the conclusion to the report
                report_text = [report_text; "CONCLUSION:"];
                if max_loading_after_outage > 85
                    line_from = results.branch(most_stressed_line_index, 1);
                    line_to = results.branch(most_stressed_line_index, 2);
                    report_text = [report_text; ...
                        sprintf("Grid is NOT N-1 SECURE. Critical overload of %.1f%% on Line %d-%d.", ...
                        max_loading_after_outage, line_from, line_to)];
                elseif max_loading_after_outage > 65
                    report_text = [report_text; "Grid is SECURE but with reduced margins. New peak loading is manageable."];
                else
                    report_text = [report_text; "Grid is SECURE. The outage is handled without significant congestion."];
                end
                
                app.TextArea.Value = report_text;
                
            else
                % If the power flow fails, it means the grid collapsed!
                plotGrid(app, []); % Reset plot to black lines
                app.TextArea.Value = [sprintf("Contingency on %s FAILED!", selected_line_name); ...
                                      "================================="; ...
                                      "The power flow did not converge."; ...
                                      " "; ...
                                      "CONCLUSION:"; ...
                                      "Grid is NOT N-1 SECURE. This outage causes a system collapse."];
            end

            app.UIAxes.Color = 'white'; 

        end

        % Button pushed function: Run24HourSolarSimulationButton
        function Run24HourSolarSimulationButtonPushed(app, event)
            % --- 1. Get User Selection and Define Parameters ---
            % <<<--- GET USER'S SELECTION FROM NEW DROPDOWN ---<<<
            selected_watch_line_name = app.SolarWatchLineDropDown.Value;
            watch_line_index = find(strcmp(app.SolarWatchLineDropDown.Items, selected_watch_line_name));
            if isempty(watch_line_index), watch_line_index = 1; end % Safety check
            
            max_solar_output_MW = 150;
            solar_bus = 6;


            % --- Create a Day/Night Color Gradient ---
            night_color = [0.05, 0.05, 0.25]; % Dark blue
            day_color = [0.01, 0.8, 1.0];     % Bright sky blue
            
            % Create a smooth transition from night to day and back to night
            day_colors = [linspace(night_color(1), day_color(1), 12)', ...
                          linspace(night_color(2), day_color(2), 12)', ...
                          linspace(night_color(3), day_color(3), 12)'];
                      
            night_colors = [linspace(day_color(1), night_color(1), 12)', ...
                            linspace(day_color(2), night_color(2), 12)', ...
                            linspace(day_color(3), night_color(3), 12)'];
                        
            hourly_background_colors = [night_color; night_color; night_color; night_color; night_color; ... % Hours 1-5 (Night)
                                        day_colors(1:7, :);  % Hours 6-12 (Sunrise)
                                        night_colors(2:7, :); % Hours 13-18 (Sunset)
                                        night_color; night_color; night_color; night_color; night_color; night_color]; % Hours 19-24 (Night)


            % --- 2. Prepare the Power System Model ---
            mpc_solar = app.mpc;
            new_gen_num = size(mpc_solar.gen, 1) + 1;
            mpc_solar.gen(new_gen_num, :) = [solar_bus, 0, 0, 50, -50, 1.0, 100, 1, 0, 0];


            % --- Create a Day/Night Color Gradient ---
            night_color = [0.05, 0.05, 0.25]; % Dark blue
            day_color = [0.5, 0.7, 1.0];     % Bright sky blue
            
            % Create a smooth transition from night to day and back to night
            day_colors = [linspace(night_color(1), day_color(1), 12)', ...
                          linspace(night_color(2), day_color(2), 12)', ...
                          linspace(night_color(3), day_color(3), 12)'];
                      
            night_colors = [linspace(day_color(1), night_color(1), 12)', ...
                            linspace(day_color(2), night_color(2), 12)', ...
                            linspace(day_color(3), night_color(3), 12)'];
                        
            hourly_background_colors = [night_color; night_color; night_color; night_color; night_color; ... % Hours 1-5 (Night)
                                        day_colors(1:7, :);  % Hours 6-12 (Sunrise)
                                        night_colors(2:7, :); % Hours 13-18 (Sunset)
                                        night_color; night_color; night_color; night_color; night_color; night_color]; % Hours 19-24 (Night)


            % --- 3. Run the 24-Hour Animation Loop ---
            app.TextArea.Value = "Running 24-hour solar animation...";
            cla(app.HourlyLoadingAxes);
            drawnow;
            
            num_branches = size(mpc_solar.branch, 1);

            solar_profile_pct = [0 0 0 0 0 0.1 0.3 0.6 0.85 0.95 1.0 1.0 0.95 0.8 0.5 0.2 0.1 0 0 0 0 0 0 0];

            hourly_loading_matrix = zeros(num_branches, 24);
            
            for hour = 1:24
                % Update solar farm output
                current_solar_MW = max_solar_output_MW * solar_profile_pct(hour);
                mpc_solar.gen(new_gen_num, 2) = current_solar_MW;
                mpc_solar.gen(new_gen_num, 9) = current_solar_MW;
                
                opt = mpoption('OUT_ALL', 0);
                results = runpf(mpc_solar, opt);
                
                if results.success
                    % Update the main grid plot for the CURRENT hour
                    plotGrid(app, results);


                    app.UIAxes.Color = hourly_background_colors(hour, :); 


                    % Update the text area to show the current hour
                    app.TextArea.Value = [sprintf("Running 24-Hour Solar Animation..."), ...
                                          sprintf("Current Hour: %d", hour)];
                    
                    % Calculate and store loading for ALL lines
                    limit_MVA = results.branch(:, 6);
                    flow_from = sqrt(results.branch(:, 14).^2 + results.branch(:, 15).^2);
                    flow_to = sqrt(results.branch(:, 16).^2 + results.branch(:, 17).^2);
                    actual_flow = max(flow_from, flow_to);
                    loading_pct = zeros(num_branches, 1);
                    has_limit = limit_MVA > 0;
                    loading_pct(has_limit) = (actual_flow(has_limit) ./ limit_MVA(has_limit)) * 100;
                    hourly_loading_matrix(:, hour) = loading_pct;
                    
                    % <<<--- PLOT THE USER'S SELECTED WATCH LINE ---<<<
                    plot(app.HourlyLoadingAxes, 1:hour, hourly_loading_matrix(watch_line_index, 1:hour), '-ob', 'LineWidth', 2);
                    title(app.HourlyLoadingAxes, sprintf('Watching %s During Sim...', selected_watch_line_name));
                    xlabel(app.HourlyLoadingAxes, 'Hour of the Day'); ylabel(app.HourlyLoadingAxes, 'Line Loading (%)');
                    grid(app.HourlyLoadingAxes, 'on'); xlim(app.HourlyLoadingAxes, [1 24]); ylim(app.HourlyLoadingAxes, [0 110]);
                    
                    drawnow;
                    pause(1);
                else
                    % Handle simulation failure
                    app.TextArea.Value = {sprintf("Simulation FAILED at Hour %d!", hour)};
                    hourly_loading_matrix(:, hour) = NaN;
                    break;
                end
            end
            
            % --- 4. Generate Final Report & Update Final Plot ---
            summary_text = ["24-Hour Simulation: Final Report"; "=============================="; " "];
            
            % Analyze the collected data
            [max_loadings_per_hour, line_indices_per_hour] = max(hourly_loading_matrix);
            [overall_max_load, peak_hour] = max(max_loadings_per_hour);
            peak_line_index = line_indices_per_hour(peak_hour);
            
            % --- Build the new, more insightful report ---
            
            % Headline: The single most critical event
            summary_text = [summary_text; "HEADLINE EVENT:"];
            summary_text = [summary_text; sprintf("The grid's most stressed moment occurred at Hour %d.", peak_hour)];
            summary_text = [summary_text; sprintf(" -> Line %d-%d hit a peak loading of %.1f%%.", ...
                mpc_solar.branch(peak_line_index, 1), mpc_solar.branch(peak_line_index, 2), overall_max_load)];
            summary_text = [summary_text; " "];
            
            % Congestion Analysis: List all lines that became congested
            summary_text = [summary_text; "CONGESTION ANALYSIS (>65% Loading):"];
            congested_lines_found = false;
            for i = 1:num_branches
                [max_loading_this_line, peak_hour_this_line] = max(hourly_loading_matrix(i, :));
                if max_loading_this_line > 65
                    summary_text = [summary_text; sprintf("  - Line %d-%d was congested, peaking at %.1f%% (Hour %d).", ...
                        mpc_solar.branch(i, 1), mpc_solar.branch(i, 2), max_loading_this_line, peak_hour_this_line)];
                    congested_lines_found = true;
                end
            end
            if ~congested_lines_found
                summary_text = [summary_text; "  - No lines experienced significant congestion."];
            end
            summary_text = [summary_text; " "];
            
            % Conclusion: The overall impact
            summary_text = [summary_text; "CONCLUSION:"];
            if overall_max_load > 85
                summary_text = [summary_text; "The solar farm causes critical congestion. The grid is NOT secure under these conditions."];
            elseif overall_max_load > 65
                summary_text = [summary_text; "The solar farm causes manageable congestion but reduces security margins."];
            else
                summary_text = [summary_text; "The solar farm is safely integrated and helps reduce daytime loading on key lines."];
            end
            
            % Display the final report in the text area
            app.TextArea.Value = summary_text;
            
            % --- FINAL PLOT UPDATE ---
            % Re-plot the bottom graph to show the Watched vs. Critical lines
            cla(app.HourlyLoadingAxes, 'reset');
            plot(app.HourlyLoadingAxes, 1:24, hourly_loading_matrix(watch_line_index, :), '-ob', 'LineWidth', 2);
            hold(app.HourlyLoadingAxes, 'on');
            plot(app.HourlyLoadingAxes, 1:24, hourly_loading_matrix(peak_line_index, :), '-or', 'LineWidth', 2);
            hold(app.HourlyLoadingAxes, 'off');
            title(app.HourlyLoadingAxes, 'Final Results: Watched Line vs. Critical Line');
            xlabel(app.HourlyLoadingAxes, 'Hour of the Day'); ylabel(app.HourlyLoadingAxes, 'Line Loading (%)');
            grid(app.HourlyLoadingAxes, 'on'); xlim(app.HourlyLoadingAxes, [1 24]); ylim(app.HourlyLoadingAxes, [20 110]);
            watched_line_label = sprintf('Watched: %s', selected_watch_line_name);
            critical_line_label = sprintf('Critical: Line %d-%d', mpc_solar.branch(peak_line_index, 1), mpc_solar.branch(peak_line_index, 2));
            legend(app.HourlyLoadingAxes, {watched_line_label, critical_line_label}, 'Location', 'best');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'System Loading Diagram')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'white')
            app.HourlyLoadingAxes = uiaxes(app.UIFigure);
            title(app.HourlyLoadingAxes, 'Hourly Loading')
            xlabel(app.HourlyLoadingAxes, 'X')
            ylabel(app.HourlyLoadingAxes, 'Y')
            zlabel(app.HourlyLoadingAxes, 'Z')
            app.PowerSystemSecurityAnalyzerLabel = uilabel(app.UIFigure);
            app.PowerSystemSecurityAnalyzerLabel.Text = 'Power System Security Analyzer';
            app.RunBaseCaseAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunBaseCaseAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunBaseCaseAnalysisButtonPushed, true);
            app.RunBaseCaseAnalysisButton.Text = 'Run Base Case Analysis';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.LineSelectorDropDownLabel = uilabel(app.UIFigure);
            app.LineSelectorDropDownLabel.Text = 'Line Selector';
            app.LineSelectorDropDown = uidropdown(app.UIFigure);
            app.LineSelectorDropDown.Items = {'{''Select a Line''}'};
            app.LineSelectorDropDown.Value = '{''Select a Line''}';
            app.SimulateLineOutageButton = uibutton(app.UIFigure, 'push');
            app.SimulateLineOutageButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateLineOutageButtonPushed, true);
            app.SimulateLineOutageButton.Text = 'Simulate Line Outage';
            app.Run24HourSolarSimulationButton = uibutton(app.UIFigure, 'push');
            app.Run24HourSolarSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @Run24HourSolarSimulationButtonPushed, true);
            app.Run24HourSolarSimulationButton.Text = 'Run 24-Hour Solar Simulation';
            app.SolarWatchLineDropDownLabel = uilabel(app.UIFigure);
            app.SolarWatchLineDropDownLabel.Text = 'Solar Watch Line';
            app.SolarWatchLineDropDown = uidropdown(app.UIFigure);
            app.SolarWatchLineDropDown.Items = {'{''Select Line to Monitor''}'};
            app.SolarWatchLineDropDown.Value = '{''Select Line to Monitor''}';
            app.Label = uilabel(app.UIFigure);
            app.Label.Interpreter = 'html';
            app.Label.Text = '<html><font color="green">■</font> Normal (&lt;65%)<br><font color="yellow">■</font> Congested (&gt;65%)<br><font color="red">■</font> Critical (&gt;85%)</html>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GridSentry

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end